classdef curve_max_exp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TraceAxes                     matlab.ui.control.UIAxes
        begintimeSpinnerLabel         matlab.ui.control.Label
        begintimeSpinner              matlab.ui.control.Spinner
        peakperiodSpinnerLabel        matlab.ui.control.Label
        peakperiodSpinner             matlab.ui.control.Spinner
        fitperiodSpinnerLabel         matlab.ui.control.Label
        fitperiodSpinner              matlab.ui.control.Spinner
        r2_fitLabel                   matlab.ui.control.Label
        RMS_baslineLabel              matlab.ui.control.Label
        max_responseLabel             matlab.ui.control.Label
        RMS_totalLabel                matlab.ui.control.Label
        LoadfullButton                matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.StateButton
        max_valLabel                  matlab.ui.control.Label
        R2_valLabel                   matlab.ui.control.Label
        RMS_valLabel                  matlab.ui.control.Label
        RMStot_valLabel               matlab.ui.control.Label
        LoadvarButton                 matlab.ui.control.Button
        ResponsedirectionButtonGroup  matlab.ui.container.ButtonGroup
        PositivegoingButton           matlab.ui.control.RadioButton
        negativegoingButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        paths=struct() %purely for loading and saving
        trace=struct() %all info for curve
    end
    

    methods (Access = private)

        % Value changed function: LoadfullButton
        function LoadfullButtonValueChanged(app, event)


            %% Getting path %%


            %importing ref property
            pathref=app.paths;
            
            %default option is just to start at pwd, otherwise use saved path
            choose=0;
            if isfield(pathref,'infil')==1
                if isfield(pathref.infil,'dirpath')==1
                    if pathref.infil.dirpath==0
                        
                    else
                        choose=1;
                    end
                end
            end
            
            %choosing old path or new one
            if choose==1
                
                [pathref.infil.filnam,pathref.infil.dirpath, ~]=...
                    uigetfile(fullfile(pathref.infil.dirpath,'*.mat'));
                
                %updating app.paths
                app.paths.infil.filnam=pathref.infil.filnam;
                app.paths.infil.dirpath=pathref.infil.dirpath;
                
            else
                [pathref.infil.filnam,pathref.infil.dirpath, ~]=uigetfile('.mat');
                
                %updating app.paths
                app.paths.infil.filnam=pathref.infil.filnam;
                app.paths.infil.dirpath=pathref.infil.dirpath;
            end
            
            disp(' ');
            disp(' ');
            disp(strcat('infile image is:    "',pathref.infil.filnam,'"'));
            
            
            %creating outfile path for adj in future%
            app.paths.outfil.dirpath=pathref.infil.dirpath;
            app.paths.outfil.filnam=strcat('peak_',pathref.infil.filnam);
            
            
            %% Importing necessary variables %%
            
            %getting variable
            tr.in=load(fullfile(pathref.infil.dirpath,pathref.infil.filnam));
            app.paths.infil.structnms=fieldnames(tr.in);
            eval(char(strcat('tr.raw=tr.in.',app.paths.infil.structnms(1),';')));
            tr.sz=size(tr.raw,2);
            tr.xax=linspace(1,tr.sz,tr.sz);


            %% initial vals and calculations for fit %%
            
            %estimate of baseline before curve (can be changed)%
            tr.bsln=floor((1/6)*tr.sz);
            app.begintimeSpinner.Limits = [1 tr.sz];
            app.begintimeSpinner.Value = tr.bsln;
            
            %estimating peak time%
            tr.peak.period=15;
            app.peakperiodSpinner.Limits = [0 tr.sz-tr.bsln];
            app.peakperiodSpinner.Value = tr.peak.period;
            
            %setting direction of curve%
            if app.negativegoingButton.Value == true
                tr.peak.going={'neg'};
                [tr.peak.val,tr.peak.ind]=min(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            elseif app.PositivegoingButton.Value == true
                tr.peak.going={'pos'};
                [tr.peak.val,tr.peak.ind]=max(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            end
            tr.peak.ind=tr.peak.ind+tr.bsln-1;
            
            %setting fit period%]
            tr.fit.period=30;
            app.fitperiodSpinner.Limits = [0 tr.sz-tr.peak.ind];
            app.fitperiodSpinner.Value = tr.fit.period;
            
            %fitting curve%
            [tr.fit.obj,tr.fit.gof]=fit(tr.xax(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                tr.raw(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                'exp1');
            tr.fit.xax=linspace(tr.xax(tr.peak.ind),tr.xax(tr.peak.ind+tr.fit.period),...
                (tr.fit.period*1000));
            tr.fit.yax=feval(tr.fit.obj,tr.fit.xax).';
            tr.fit.peak=tr.fit.yax(1);


            %% intializing displays and reports %%
            
            %max est%
            app.max_valLabel.Text = num2str(tr.fit.peak);
            app.max_valLabel.Visible = 'on';
            
            %r squared%
            app.R2_valLabel.Text = num2str(tr.fit.gof.adjrsquare);
            app.R2_valLabel.Visible = 'on';
            
            %bsln rms%
            tr.rms.bsln=rms(tr.raw(1:tr.bsln));
            app.RMS_valLabel.Text = num2str(tr.rms.bsln);
            app.RMS_valLabel.Visible = 'on';
            
            %tot rms%
            tr.rms.tot=rms(tr.raw);
            app.RMStot_valLabel.Text = num2str(tr.rms.tot);
            app.RMStot_valLabel.Visible = 'on';
            
            %plot%
            plot(app.TraceAxes,tr.xax,tr.raw,'Color',[0 0 0],'LineWidth',1)
            hold(app.TraceAxes, 'on')
            plot(app.TraceAxes,tr.fit.xax,tr.fit.yax,...
                'Color',[0.69 0 0],'LineWidth',2)
            hold(app.TraceAxes, 'off')
            
            %setting up to adust fits%
            app.begintimeSpinner.Editable = 'on';
            
            app.peakperiodSpinner.Editable = 'on';
            
            app.fitperiodSpinner.Editable = 'on';
            
            
            app.trace=tr;
            
            
        end

        % Selection changed function: ResponsedirectionButtonGroup
        function ResponsedirectionButtonGroupSelectionChanged(app, event)

            tr=app.trace;
            
            %setting direction of curve%
            if app.negativegoingButton.Value == true
                tr.peak.going={'neg'};
                [tr.peak.val,tr.peak.ind]=min(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            elseif app.PositivegoingButton.Value == true
                tr.peak.going={'pos'};
                [tr.peak.val,tr.peak.ind]=max(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            end
            
            tr.peak.ind=tr.peak.ind+tr.bsln;
            
            
            %fitting curve%
            [tr.fit.obj,tr.fit.gof]=fit(tr.xax(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                tr.raw(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                'exp1');
            tr.fit.xax=linspace(tr.xax(tr.peak.ind),tr.xax(tr.peak.ind+tr.fit.period),...
                (tr.fit.period*1000));
            tr.fit.yax=feval(tr.fit.obj,tr.fit.xax).';
            tr.fit.peak=tr.fit.yax(1);


            %% intializing displays and reports %%
            
            %max est%
            app.max_valLabel.Text = num2str(tr.fit.peak);
            
            %r squared%
            app.R2_valLabel.Text = num2str(tr.fit.gof.adjrsquare);
            
            %bsln rms%
            tr.rms.bsln=rms(tr.raw(1:tr.bsln));
            app.RMS_valLabel.Text = num2str(tr.rms.bsln);
            
            %tot rms%
            tr.rms.tot=rms(tr.raw);
            app.RMStot_valLabel.Text = num2str(tr.rms.tot);
            
            %plot%
            plot(app.TraceAxes,tr.xax,tr.raw,'Color',[0 0 0],'LineWidth',1)
            hold(app.TraceAxes, 'on')
            plot(app.TraceAxes,tr.fit.xax,tr.fit.yax,...
                'Color',[0.69 0 0],'LineWidth',2)
            hold(app.TraceAxes, 'off')


            app.trace=tr;
            
        end

        % Value changed function: begintimeSpinner
        function begintimeSpinnerValueChanged(app, event)
            
            tr=app.trace;
            
            
            %estimate of baseline before curve (can be changed)%
            tr.bsln = app.begintimeSpinner.Value;
            
            
            %setting direction of curve%
            if app.negativegoingButton.Value == true
                tr.peak.going={'neg'};
                [tr.peak.val,tr.peak.ind]=min(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            elseif app.PositivegoingButton.Value == true
                tr.peak.going={'pos'};
                [tr.peak.val,tr.peak.ind]=max(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            end
            tr.peak.ind=tr.peak.ind+tr.bsln;
            
            
            %fitting curve%
            [tr.fit.obj,tr.fit.gof]=fit(tr.xax(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                tr.raw(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                'exp1');
            tr.fit.xax=linspace(tr.xax(tr.peak.ind),tr.xax(tr.peak.ind+tr.fit.period),...
                (tr.fit.period*1000));
            tr.fit.yax=feval(tr.fit.obj,tr.fit.xax).';
            tr.fit.peak=tr.fit.yax(1);


            %% intializing displays and reports %%
            
            
            %max est%
            app.max_valLabel.Text = num2str(tr.fit.peak);
             
            
            %r squared%
            app.R2_valLabel.Text = num2str(tr.fit.gof.adjrsquare);
            
            
            %bsln rms%
            tr.rms.bsln=rms(tr.raw(1:tr.bsln));
            app.RMS_valLabel.Text = num2str(tr.rms.bsln);
            
            
            %tot rms%
            tr.rms.tot=rms(tr.raw);
            app.RMStot_valLabel.Text = num2str(tr.rms.tot);
            
            
            %plot%
            plot(app.TraceAxes,tr.xax,tr.raw,'Color',[0 0 0],'LineWidth',1)
            hold(app.TraceAxes, 'on')
            plot(app.TraceAxes,tr.fit.xax,tr.fit.yax,...
                'Color',[0.69 0 0],'LineWidth',2)
            hold(app.TraceAxes, 'off')
            
            
            app.trace=tr;
            
        end

        % Value changed function: peakperiodSpinner
        function peakperiodSpinnerValueChanged(app, event)
            
            tr=app.trace;
            
            
             %estimating peak time%
            tr.peak.period = app.peakperiodSpinner.Value;
            
            %setting direction of curve%
            if app.negativegoingButton.Value == true
                tr.peak.going={'neg'};
                [tr.peak.val,tr.peak.ind]=min(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            elseif app.PositivegoingButton.Value == true
                tr.peak.going={'pos'};
                [tr.peak.val,tr.peak.ind]=max(tr.raw(tr.bsln:(tr.bsln+tr.peak.period)));
            end
            tr.peak.ind=tr.peak.ind+tr.bsln;
            
            
            %fitting curve%
            [tr.fit.obj,tr.fit.gof]=fit(tr.xax(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                tr.raw(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                'exp1');
            tr.fit.xax=linspace(tr.xax(tr.peak.ind),tr.xax(tr.peak.ind+tr.fit.period),...
                (tr.fit.period*1000));
            tr.fit.yax=feval(tr.fit.obj,tr.fit.xax).';
            tr.fit.peak=tr.fit.yax(1);


            %% intializing displays and reports %%
            
            %max est%
            app.max_valLabel.Text = num2str(tr.fit.peak);
            
            %r squared%
            app.R2_valLabel.Text = num2str(tr.fit.gof.adjrsquare);
            
            
            %plot%
            plot(app.TraceAxes,tr.xax,tr.raw,'Color',[0 0 0],'LineWidth',1)
            hold(app.TraceAxes, 'on')
            plot(app.TraceAxes,tr.fit.xax,tr.fit.yax,...
                'Color',[0.69 0 0],'LineWidth',2)
            hold(app.TraceAxes, 'off')
            
            
            app.trace=tr;
        end

        % Value changed function: fitperiodSpinner
        function fitperiodSpinnerValueChanged(app, event)
            tr=app.trace;
             
            %setting fit period%]
            tr.fit.period = app.fitperiodSpinner.Value;

            
            %fitting curve%
            [tr.fit.obj,tr.fit.gof]=fit(tr.xax(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                tr.raw(tr.peak.ind:(tr.peak.ind+tr.fit.period)).',...
                'exp2');
            tr.fit.xax=linspace(tr.xax(tr.peak.ind),tr.xax(tr.peak.ind+tr.fit.period),...
                (tr.fit.period*1000));
            tr.fit.yax=feval(tr.fit.obj,tr.fit.xax).';
            tr.fit.peak=tr.fit.yax(1);


            %% intializing displays and reports %%
            
            %max est%
            app.max_valLabel.Text = num2str(tr.fit.peak);

            
            %r squared%
            app.R2_valLabel.Text = num2str(tr.fit.gof.adjrsquare);

            
            %plot%
            plot(app.TraceAxes,tr.xax,tr.raw,'Color',[0 0 0],'LineWidth',1)
            hold(app.TraceAxes, 'on')
            plot(app.TraceAxes,tr.fit.xax,tr.fit.yax,...
                'Color',[0.69 0 0],'LineWidth',2)
            hold(app.TraceAxes, 'off')


            app.trace=tr;
            
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            
            tr=app.trace;
            
            save(fullfile(app.paths.outfil.dirpath,app.paths.outfil.filnam),'tr')
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TraceAxes = uiaxes(app.UIFigure);
            xlabel(app.TraceAxes, 'Frames')
            ylabel(app.TraceAxes, 'dFoF')
            app.begintimeSpinnerLabel = uilabel(app.UIFigure);
            app.begintimeSpinnerLabel.Text = 'begin time';
            app.begintimeSpinner = uispinner(app.UIFigure);
            app.begintimeSpinner.Limits = [1 Inf];
            app.begintimeSpinner.ValueChangedFcn = createCallbackFcn(app, @begintimeSpinnerValueChanged, true);
            app.begintimeSpinner.Editable = 'off';
            app.begintimeSpinner.Value = 1;
            app.peakperiodSpinnerLabel = uilabel(app.UIFigure);
            app.peakperiodSpinnerLabel.Text = 'peak period';
            app.peakperiodSpinner = uispinner(app.UIFigure);
            app.peakperiodSpinner.Limits = [0 Inf];
            app.peakperiodSpinner.ValueChangedFcn = createCallbackFcn(app, @peakperiodSpinnerValueChanged, true);
            app.peakperiodSpinner.Editable = 'off';
            app.peakperiodSpinner.Value = 10;
            app.fitperiodSpinnerLabel = uilabel(app.UIFigure);
            app.fitperiodSpinnerLabel.Text = 'fit period';
            app.fitperiodSpinner = uispinner(app.UIFigure);
            app.fitperiodSpinner.Limits = [0 Inf];
            app.fitperiodSpinner.ValueChangedFcn = createCallbackFcn(app, @fitperiodSpinnerValueChanged, true);
            app.fitperiodSpinner.Editable = 'off';
            app.fitperiodSpinner.Value = 1;
            app.r2_fitLabel = uilabel(app.UIFigure);
            app.r2_fitLabel.Text = 'r2_fit';
            app.RMS_baslineLabel = uilabel(app.UIFigure);
            app.RMS_baslineLabel.Text = 'RMS_basline';
            app.max_responseLabel = uilabel(app.UIFigure);
            app.max_responseLabel.Text = 'max_response';
            app.RMS_totalLabel = uilabel(app.UIFigure);
            app.RMS_totalLabel.Text = 'RMS_total';
            app.LoadfullButton = uibutton(app.UIFigure, 'state');
            app.LoadfullButton.ValueChangedFcn = createCallbackFcn(app, @LoadfullButtonValueChanged, true);
            app.LoadfullButton.Text = 'Load full';
            app.SaveButton = uibutton(app.UIFigure, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.max_valLabel = uilabel(app.UIFigure);
            app.max_valLabel.Visible = 'off';
            app.max_valLabel.Text = 'max_val';
            app.R2_valLabel = uilabel(app.UIFigure);
            app.R2_valLabel.Visible = 'off';
            app.R2_valLabel.Text = 'R2_val';
            app.RMS_valLabel = uilabel(app.UIFigure);
            app.RMS_valLabel.Visible = 'off';
            app.RMS_valLabel.Text = 'RMS_val';
            app.RMStot_valLabel = uilabel(app.UIFigure);
            app.RMStot_valLabel.Visible = 'off';
            app.RMStot_valLabel.Text = 'RMStot_val';
            app.LoadvarButton = uibutton(app.UIFigure, 'push');
            app.LoadvarButton.Text = 'Load var';
            app.ResponsedirectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ResponsedirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResponsedirectionButtonGroupSelectionChanged, true);
            app.ResponsedirectionButtonGroup.Title = 'Response direction';
            app.PositivegoingButton = uiradiobutton(app.ResponsedirectionButtonGroup);
            app.PositivegoingButton.Text = 'Positive going';
            app.negativegoingButton = uiradiobutton(app.ResponsedirectionButtonGroup);
            app.negativegoingButton.Text = 'negative going';
            app.negativegoingButton.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = curve_max_exp1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end