classdef ampdist_fit_exp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ImportDataMenu                  matlab.ui.container.Menu
        AppendpreviousMenu              matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ReviewaplitudepointsTab         matlab.ui.container.Tab
        axAvD                           matlab.ui.control.UIAxes
        axTvD                           matlab.ui.control.UIAxes
        axCurrent                       matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        SelectstartptButton             matlab.ui.control.Button
        StartnotselectedLabel           matlab.ui.control.Label
        SelectendptButton               matlab.ui.control.Button
        EndnotselectedLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SeerefimandROIsButton           matlab.ui.control.Button
        SeefulltraceButton              matlab.ui.control.Button
        WindowLabel                     matlab.ui.control.Label
        WindownumberSpinner             matlab.ui.control.Spinner
        ExcludeButton                   matlab.ui.control.StateButton
        NoexclusionsyetLabel            matlab.ui.control.Label
        BaselineRMSLabel                matlab.ui.control.Label
        MaxampLabel                     matlab.ui.control.Label
        ZeroButton                      matlab.ui.control.StateButton
        NotZeroLabel                    matlab.ui.control.Label
        AdjustpeakwindowSpinnerLabel    matlab.ui.control.Label
        AdjustpeakwindowSpinner         matlab.ui.control.Spinner
        AdjustcrvfitwindowSpinnerLabel  matlab.ui.control.Label
        AdjustcrvfitwindowSpinner       matlab.ui.control.Spinner
        ZerostoendButton                matlab.ui.control.StateButton
        RSquaredLabel                   matlab.ui.control.Label
        AdjtoendButton                  matlab.ui.control.StateButton
        EstimateDistTab                 matlab.ui.container.Tab
        axCrveFit                       matlab.ui.control.UIAxes
        SaveresultsButton               matlab.ui.control.Button
        PolycurveLabel                  matlab.ui.control.Label
        BlackpointsfitredcurvefitLabel  matlab.ui.control.Label
        EstimateddistanceLabel          matlab.ui.control.Label
        FitdataButton_2                 matlab.ui.control.Button
        ExptsavedLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        paths=struct() %purely for loading and saving
        ref=struct()%properties to refer to from workspac
        currentplots=struct()%main property for updating
        winfits=struct()%main output
    end
    

    methods (Access = private)

        % Menu selected function: ImportDataMenu
        function ImportDataMenuSelected(app, event)


            %% Getting path %%


            %importing ref property
            pathref=app.paths;
            
            %default option is just to start at pwd, otherwise use saved path
            choose=0;
            if isfield(pathref,'infil')==1
                if isfield(pathref.infil,'dirpath')==1
                    if pathref.infil.dirpath==0
                        
                    else
                        choose=1;
                    end
                end
            end
            
            %choosing old path or new one
            if choose==1
                
                [pathref.infil.filnam,pathref.infil.dirpath, ~]=...
                    uigetfile(fullfile(pathref.infil.dirpath,'*.mat'));
                
                %updating app.paths
                app.paths.infil.filnam=pathref.infil.filnam;
                app.paths.infil.dirpath=pathref.infil.dirpath;
                
            else
                [pathref.infil.filnam,pathref.infil.dirpath, ~]=uigetfile('.mat');
                
                %updating app.paths
                app.paths.infil.filnam=pathref.infil.filnam;
                app.paths.infil.dirpath=pathref.infil.dirpath;
            end
            
            disp(' ');
            disp(' ');
            disp(strcat('infile image is:    "',pathref.infil.filnam,'"'));


            %% Importing necessary variables %%


            %getting variables from workspace
            fullvar.rois=load(fullfile(pathref.infil.dirpath,pathref.infil.filnam),'rois');
            fullvar.tools=load(fullfile(pathref.infil.dirpath,pathref.infil.filnam),'tools');
            fullvar.fxnodist=load(fullfile(pathref.infil.dirpath,pathref.infil.filnam),'fxnodist');
            fullvar.repim=load(fullfile(pathref.infil.dirpath,pathref.infil.filnam),'tif');
            
            
            %making curr for stats calc
            curr.fod=fullvar.fxnodist.fxnodist;
            curr.trace.xax=linspace(...
                -fullvar.tools.tools.parameters.stim(1)/fullvar.tools.tools.parameters.im(2),...
                fullvar.tools.tools.parameters.stim(2)/fullvar.tools.tools.parameters.im(2),...
                ((fullvar.tools.tools.parameters.stim(1)+fullvar.tools.tools.parameters.stim(2))/...
                fullvar.tools.tools.parameters.im(1))+1);
            curr.trace.xaxfull=fullvar.tools.tools.figs.fullxax;
            
            
            %setting currentplot unchanging properties
            curr.colors.aboveRMS = [0 0.6 0];
            curr.colors.belowRMS = [0.6 0.4 0.4];
            curr.colors.excluded = [0.5 0.5 0.5];
            curr.colors.zeroed = [0 0 0];
            curr.num=1;
            
            
            %loading multifields sequentially
            stats(size(fullvar.rois.rois.windows,2),1)=struct('ave',[],'t_stderr',[],'b_stderr',[],'f',[],'rms',[],'wincrv',[]);
            masks(size(fullvar.rois.rois.windows,2),1)=struct('roi',[],'bkgrnd',[]);
            
            
            for i=1:size(fullvar.rois.rois.windows,2)
                
                %grabbing stats%
                stats(i).f=fullvar.rois.rois.windows(i).f;
                stats(i).ave=fullvar.rois.rois.windows(i).compiled.stats.ave;
                stats(i).t_stderr=fullvar.rois.rois.windows(i).compiled.stats.t_stderr;
                stats(i).b_stderr=fullvar.rois.rois.windows(i).compiled.stats.b_stderr;
                
                
                %calculating rms%
                stats(i).rms=1-rms(fullvar.rois.rois.windows(i).compiled.stats.ave(...
                    1:(fullvar.tools.tools.parameters.stim(1)/...
                    fullvar.tools.tools.parameters.im(1))));
                
                
                %calculating fit curve%
                stats(i).wincrv.st=fullvar.fxnodist.fxnodist.tmax(i)+...
                    (fullvar.tools.tools.parameters.stim(1)/fullvar.tools.tools.parameters.im(1));%st time in frames
                
                stats(i).wincrv.peakwin=1000/...
                    fullvar.tools.tools.parameters.im(1);%arbitrary num that changes for peak detection%
                
                stats(i).wincrv.fitwin=2*stats(i).wincrv.peakwin;%arbitrary num that changes for amp curve fit%
                
                stats(i).wincrv.end=stats(i).wincrv.st+stats(i).wincrv.fitwin;
                
                [stats(i).wincrv.obj,stats(i).wincrv.gof]=fit(...
                    (curr.trace.xax(stats(i).wincrv.st:stats(i).wincrv.end).'),...
                    (stats(i).ave(stats(i).wincrv.st:stats(i).wincrv.end).'),...
                    'exp2');
                
                curr.fod.amp(i)=1-(stats(i).wincrv.obj(curr.trace.xax(stats(i).wincrv.st)));
                
                curr.fod.full594pts(i,:)=fullvar.rois.rois.windows(i).compiled594.stats.ave;
                
                stats(i).wincrv.plotx=curr.trace.xax(stats(i).wincrv.st:stats(i).wincrv.end);
                stats(i).wincrv.ploty=stats(i).wincrv.obj(stats(i).wincrv.plotx);
                
                
                %grabbind masks%
                masks(i).roi=fullvar.rois.rois.windows(i).mask;
                masks(i).bkgrnd=fullvar.rois.rois.bkgrnd(i).mask;
                
            end
            
            
            %populating properties
            app.ref.parameters=fullvar.tools.tools.parameters;
            app.ref.stats=stats;
            app.ref.repim=fullvar.repim.tif.vim.ref;
            app.ref.masks=masks;


            %% initializing functions and properties with expt. specific info %%


            %window spinner
            app.WindownumberSpinner.Visible = 'on';
            app.WindownumberSpinner.Limits = [1 size(fullvar.rois.rois.windows,2)];
            app.WindownumberSpinner.Value=1;
            
            %start range button
            app.SelectstartptButton.Visible = 'on';
            app.winfits.st=[];
            
            
            %end range button
            app.SelectendptButton.Visible = 'on';
            app.winfits.end=[];
            
            
            %exclude button
            app.ExcludeButton.Visible = 'on';
            app.NoexclusionsyetLabel.Visible = 'on';
            app.winfits.exclusions=zeros(size(fullvar.rois.rois.windows,2),1);
            
            
            %Zero buttons
            app.ZeroButton.Visible = 'on';
            app.NotZeroLabel.Visible = 'on';
            app.winfits.zeros=zeros(size(fullvar.rois.rois.windows,2),1);
            app.ZerostoendButton.Visible = 'on';
            
            
            %view ROIs
            app.SeerefimandROIsButton.Visible = 'on';
            
            
            %view full trace
            app.SeefulltraceButton.Visible = 'on';
            
            
            %RMS title
            app.BaselineRMSLabel.Visible = 'on';
            
            
            %Max amp title
            app.MaxampLabel.Visible = 'on';
            
            
            %Change fit window spinner
            app.AdjustcrvfitwindowSpinner.Visible = 'on';
            
            temp=app.ref.parameters.im(1)/app.ref.parameters.im(2);
            
            app.AdjustcrvfitwindowSpinner.Step = temp;
            
            app.AdjustcrvfitwindowSpinner.Limits = [temp (app.ref.parameters.stim(2)/app.ref.parameters.im(2))-...
                (curr.fod.tmax(curr.num)*temp)];
            
            app.AdjustcrvfitwindowSpinner.Value = app.ref.stats(curr.num).wincrv.fitwin*...
                app.AdjustcrvfitwindowSpinner.Step;
            
            
            %Change peak window
            app.AdjustpeakwindowSpinner.Visible = 'on';
            
            app.AdjustpeakwindowSpinner.Step = temp;
            
            app.AdjustpeakwindowSpinner.Limits = [temp (app.ref.parameters.stim(2)/app.ref.parameters.im(2))];
            
            app.AdjustpeakwindowSpinner.Value = 1;
            
            %Rsquared box
            app.RSquaredLabel.Visible = 'on';
            
            %Adj to end button
            app.AdjtoendButton.Visible = 'on';


            %% plots %%


            %setting color for trace plot
            if app.winfits.exclusions(curr.num)==1
                curr.colors.temp=curr.colors.excluded;
            elseif app.winfits.zeros(curr.num)==1
                curr.colors.temp=curr.colors.zeroed;
            elseif curr.fod.amp(curr.num)>app.ref.stats(curr.num).rms
                curr.colors.temp=curr.colors.aboveRMS;
            else
                curr.colors.temp=curr.colors.belowRMS;
            end
            
            app.currentplots=curr;
            
            
            %Max amp vs distance
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,curr.fod.xax,curr.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,curr.fod.xax(curr.num),curr.fod.amp(curr.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,curr.fod.xax,curr.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)
            
            
            %Max time vs distance
            yyaxis(app.axTvD,'left')
            plot(app.axTvD,curr.fod.xax,curr.fod.tmax,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axTvD,'time to ASAP3er peak (frames)', 'FontSize',12)
            hold(app.axTvD, 'on')
            plot(app.axTvD,curr.fod.xax(curr.num),curr.fod.tmax(curr.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axTvD, 'off')
            yyaxis(app.axTvD,'right')
            plot(app.axTvD,curr.fod.xax,curr.fod.tmax594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axTvD,'time to 594 peak (frames)', 'FontSize',12)
            
            
            %current trace
            plot(app.axCurrent,curr.trace.xax,app.ref.stats(curr.num).t_stderr,...
                'Color',curr.colors.temp,'LineWidth',1)
            plot(app.axCurrent,curr.trace.xax,app.ref.stats(curr.num).b_stderr,...
                'Color',curr.colors.temp,'LineWidth',1)
            patch(app.axCurrent,[curr.trace.xax,fliplr(curr.trace.xax)],...
                [app.ref.stats(curr.num).t_stderr,...
                fliplr(app.ref.stats(curr.num).b_stderr)],...
                [0.97 0.97 0.97])
            hold(app.axCurrent, 'on')
            plot(app.axCurrent,curr.trace.xax,app.ref.stats(curr.num).ave,...
                'Color',curr.colors.temp,'LineWidth',3)
            plot(app.axCurrent,app.ref.stats(curr.num).wincrv.plotx,...
                app.ref.stats(curr.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            hold(app.axCurrent, 'off')
            
            
            %RMS box
            app.BaselineRMSLabel.Text = strcat('Baseline RMS = ',num2str(app.ref.stats(curr.num).rms));
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(curr.fod.amp(curr.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(stats(i).wincrv.gof.adjrsquare));
            
            
        end

        % Value changed function: ExcludeButton
        function ExcludeButtonValueChanged(app, event)
            
            app.winfits.exclusions(app.currentplots.num)=app.ExcludeButton.Value;
            
            %% update excluded state button %%
            switch app.ExcludeButton.Value
                case 0
                    
                    app.NoexclusionsyetLabel.FontColor = [0.0 0.7 0.0];
                    
                    app.NoexclusionsyetLabel.Text = 'Window not excluded';
                    
                    app.ExcludeButton.Text = 'Exclude';
                    
                case 1
                    
                    app.NoexclusionsyetLabel.FontColor = [0.6 0.0 0.0];
                    
                    app.NoexclusionsyetLabel.Text = 'Window excluded';
                    
                    app.ExcludeButton.Text = 'Un-exclude';
                    
            end
            
            
            %setting color for trace plot
            if app.winfits.exclusions(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.excluded;
            elseif app.winfits.zeros(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.zeroed;
            elseif app.currentplots.fod.amp(app.currentplots.num)>app.ref.stats(app.currentplots.num).rms
                app.currentplots.colors.temp=app.currentplots.colors.aboveRMS;
            else
                app.currentplots.colors.temp=app.currentplots.colors.belowRMS;
            end
            
            %current trace
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            
            hold(app.axCurrent, 'off')
            
            
        end

        % Value changed function: WindownumberSpinner
        function WindownumberSpinnerValueChanged(app, event)
            
            app.currentplots.num = app.WindownumberSpinner.Value;
            
            
            %setting color for trace plot
            if app.winfits.exclusions(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.excluded;
            elseif app.winfits.zeros(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.zeroed;
            elseif app.currentplots.fod.amp(app.currentplots.num)>app.ref.stats(app.currentplots.num).rms
                app.currentplots.colors.temp=app.currentplots.colors.aboveRMS;
            else
                app.currentplots.colors.temp=app.currentplots.colors.belowRMS;
            end


            %% plots %%


            %Max amp vs distance
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.amp(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)
            
            
            %Max time vs distance
            yyaxis(app.axTvD,'left')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axTvD,'time to ASAP3er peak (frames)', 'FontSize',12)
            hold(app.axTvD, 'on')
            plot(app.axTvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.tmax(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axTvD, 'off')
            yyaxis(app.axTvD,'right')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axTvD,'time to 594 peak (frames)', 'FontSize',12)
            
            
            %current trace
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).t_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).b_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            patch(app.axCurrent,[app.currentplots.trace.xax,fliplr(app.currentplots.trace.xax)],...
                [app.ref.stats(app.currentplots.num).t_stderr,fliplr(app.ref.stats(app.currentplots.num).b_stderr)],...
                [0.97 0.97 0.97])
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            
            hold(app.axCurrent, 'off')
            
            
            %RMS box
            app.BaselineRMSLabel.Text = strcat('Baseline RMS = ',num2str(app.ref.stats(app.currentplots.num).rms));
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(app.currentplots.fod.amp(app.currentplots.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(app.ref.stats(app.currentplots.num).wincrv.gof.adjrsquare));


            %% update excluded state button %%
            app.ExcludeButton.Value=app.winfits.exclusions(app.currentplots.num);
            
            switch app.winfits.exclusions(app.currentplots.num)
                case 0
                    
                    app.NoexclusionsyetLabel.FontColor = [0.0 0.7 0.0];
                    
                    app.NoexclusionsyetLabel.Text = 'Window not excluded';
                    
                    app.ExcludeButton.Text = 'Exclude';
                    
                case 1
                    
                    app.NoexclusionsyetLabel.FontColor = [0.6 0.0 0.0];
                    
                    app.NoexclusionsyetLabel.Text = 'Current window excluded';
                    
                    app.ExcludeButton.Text = 'Un-exclude';
                    
            end


            %% update zero state button %%
            app.ZeroButton.Value=app.winfits.zeros(app.currentplots.num);
            
            
            switch app.winfits.zeros(app.currentplots.num)
                case 0
                    
                    app.NotZeroLabel.FontColor = [0.0 0.7 0.0];
                    
                    app.NotZeroLabel.Text = 'Window not zeroed';
                    
                    app.ZeroButton.Text = 'Make zero';
                    
                case 1
                    
                    app.NotZeroLabel.FontColor = [0.6 0.0 0.0];
                    
                    app.NotZeroLabel.Text = 'Current window zeroed';
                    
                    app.ZeroButton.Text = 'Un-zero';
                    
            end


            %% update adjpeak button %%
            app.AdjustpeakwindowSpinner.Value = app.ref.stats(app.currentplots.num).wincrv.peakwin*...
                app.AdjustpeakwindowSpinner.Step;


            %% update adjfit button %%
            app.AdjustcrvfitwindowSpinner.Value = app.ref.stats(app.currentplots.num).wincrv.fitwin*...
                app.AdjustcrvfitwindowSpinner.Step;
            
            app.AdjustcrvfitwindowSpinner.Limits = [app.AdjustcrvfitwindowSpinner.Step (app.ref.parameters.stim(2)/app.ref.parameters.im(2))-...
                (app.currentplots.fod.tmax(app.currentplots.num)*app.AdjustcrvfitwindowSpinner.Step)];


        end

        % Button pushed function: SeerefimandROIsButton
        function SeerefimandROIsButtonPushed(app, event)
            
            %repim
            figure('Color','white')
            imshow(app.ref.repim);
            hold on
            visboundaries(app.ref.masks(app.currentplots.num).roi,'Color','r','LineWidth', 0.25);
            visboundaries(app.ref.masks(app.currentplots.num).bkgrnd,'Color','c','LineWidth', 0.25);
            hold off
            
            
        end

        % Button pushed function: SelectstartptButton
        function SelectstartptButtonPushed(app, event)
            
            app.winfits.st=app.currentplots.num;
            
            app.StartnotselectedLabel.Text = strcat('Start is window',num2str(app.currentplots.num));
            
            test=app.winfits.end;
            
            if test>0
                app.FitdataButton_2.Visible = 'on';
            else
                app.FitdataButton_2.Visible = 'off';
            end
            
            
        end

        % Button pushed function: SelectendptButton
        function SelectendptButtonPushed(app, event)
            
            app.winfits.end=app.currentplots.num;
            
            app.EndnotselectedLabel.Text = strcat('End is window',num2str(app.currentplots.num));
            
            test=app.winfits.st;
            
            if test>0
                app.FitdataButton_2.Visible = 'on';
            else
                app.FitdataButton_2.Visible = 'off';
            end
            
            
        end

        % Button pushed function: SaveresultsButton
        function FitamplitudesButtonValueChanged(app, event)
            
            app.ExptsavedLabel.Visible = 'off';
            
            
            pathref=app.paths;
            DistEst.curr=app.currentplots;
            DistEst.fits=app.winfits;
            DistEst.stats=app.ref.stats;
            
            save(fullfile(pathref.infil.dirpath,'distestimate.mat'),'DistEst');
            
            app.ExptsavedLabel.Visible = 'on';
            
            figure('Color','w')
            plot((DistEst.curr.fod.xax-DistEst.curr.fod.xax(DistEst.fits.st)),DistEst.curr.fod.amp,...
                'Color',[0.6 0.6 0.6],'LineWidth',1,'Marker','o')
            ylabel('max ASAP3er dFoF', 'FontSize',12)
            hold on
            plot(DistEst.fits.curve.xax,DistEst.fits.curve.yax,...
                'Color',[0 0 0],'LineWidth',1,'Marker','o')
            plot(DistEst.fits.curve.xax(DistEst.fits.excludeindex),DistEst.fits.curve.yax(DistEst.fits.excludeindex),...
                'Color',[0.6 0.6 0.6],'LineWidth',1,'Marker','o')
            plot(DistEst.fits.curve.obj)
            plot(DistEst.fits.distest,(0.5*DistEst.fits.curve.yax(1)),...
                'Color',[0.95 0 0],'Marker','o')
            hold off
            box off
            savefig(fullfile(pathref.infil.dirpath,'fod_crv.fig'))


        end

        % Callback function
        function FitdataButtonValueChanged(app, event)
            
        end

        % Callback function
        function SaveresultsButtonPushed(app, event)
            
        end

        % Button pushed function: FitdataButton_2
        function FitdataButton_2Pushed(app, event)
            
            fits=app.winfits;
            curr=app.currentplots;
            valzeros=abs(fits.zeros-1);
            
            
            %calculating fit polynomial
            fits.excludeindex=find(fits.exclusions(fits.st:fits.end)==1);
            fits.curve.xax=(curr.fod.xax(fits.st:fits.end)-curr.fod.xax(fits.st)).';
            fits.curve.yax=(curr.fod.amp(fits.st:fits.end).').*valzeros(fits.st:fits.end);
            [fits.curve.obj,fits.curve.gof]=fit(fits.curve.xax,fits.curve.yax,...
                'exp2','Exclude',fits.excludeindex);
            
            
            %creating curve report var for display and sav
            temp.coeffnm=coeffnames(fits.curve.obj);
            temp.coeffval=coeffvalues(fits.curve.obj);
            temp.coeffconf=confint(fits.curve.obj,0.95);
            fits.curve.coeffreport(1)="Coefficient values:";
            
            for i=1:size(temp.coeffnm,1)
                fits.curve.coeffreport(1+i,1)=...
                    string(strcat(temp.coeffnm(i),'=',num2str(temp.coeffval(i))));
            end
            fits.curve.coeffreport(size(temp.coeffnm,1)+2,1)="";
            fits.curve.coeffreport(size(temp.coeffnm,1)+3,1)="Coefficient 95% conf. intervals";
            for i=1:size(temp.coeffnm,1)
                fits.curve.coeffreport(i+size(temp.coeffnm,1)+3,1)=...
                    string(strcat(temp.coeffnm(i),'_CI_=',...
                    num2str(temp.coeffconf(1,i)),'_to_',num2str(temp.coeffconf(1,i))));
            end
            
            fits.curve.coeffreport((2*size(temp.coeffnm,1))+4,1)="";
            fits.curve.coeffreport((2*size(temp.coeffnm,1))+5,1)=...
                string(strcat('rsquare=',num2str(fits.curve.gof.rsquare)));
            fits.curve.coeffreport((2*size(temp.coeffnm,1))+6,1)="";
            fits.curve.coeffreport((2*size(temp.coeffnm,1))+7,1)=...
                string(strcat('rmse=',num2str(fits.curve.gof.rmse)));


            %ensuring start is before est point
            temp.inc=fits.curve.xax(1);
            temp.st=feval(fits.curve.obj,(temp.inc));
            
            while temp.st<(0.5*fits.curve.yax(1))
                temp.inc=temp.inc-0.5;
                temp.st=feval(fits.curve.obj,(temp.inc));
            end
            
            temp.st=temp.inc;
            
            
            %ensuring far bound is past est point
            temp.inc=fits.curve.xax(end);
            temp.end=feval(fits.curve.obj,(temp.inc));
            
            while temp.end>(0.5*fits.curve.yax(1))
                temp.inc=temp.inc+.5;
                temp.end=feval(fits.curve.obj,(temp.inc));
            end
            
            temp.end=temp.inc;
            
            
            %plotting with 1000 points between each x point and interpolating half max x%
            temp.xax=linspace(temp.st,temp.end,(1000*(temp.end-temp.st)));
            temp.yax(:)=feval(fits.curve.obj,temp.xax(:));
            fits.distest=interp1(temp.yax,temp.xax,0.5*fits.curve.yax(1));
            
            
            %printing
            app.PolycurveLabel.Visible = 'on';
            app.PolycurveLabel.Text = fits.curve.coeffreport;
            app.EstimateddistanceLabel.Visible = 'on';
            app.EstimateddistanceLabel.Text = strcat('Estimated dist=',num2str(fits.distest));
            
            
            %plotting
            plot(app.axCrveFit,(curr.fod.xax-curr.fod.xax(fits.st)),curr.fod.amp,...
                'Color',[0.6 0.6 0.6],'LineWidth',1,'Marker','o')
            ylabel(app.axCrveFit,'max ASAP3er dFoF', 'FontSize',12)
            hold(app.axCrveFit, 'on')
            plot(app.axCrveFit,fits.curve.xax,fits.curve.yax,...
                'Color',[0 0 0],'LineWidth',1,'Marker','o')
            plot(app.axCrveFit,fits.curve.xax(fits.excludeindex),fits.curve.yax(fits.excludeindex),...
                'Color',[0.6 0.6 0.6],'LineWidth',1,'Marker','o')
            plot(app.axCrveFit,temp.xax,temp.yax,'Color',[0.8 0 0],'LineWidth',1)
            plot(app.axCrveFit,fits.distest,(0.5*fits.curve.yax(1)),...
                'Color',[0.95 0 0],'Marker','o')
            hold(app.axCrveFit, 'off')
            
            
            app.winfits=fits;
            app.SaveresultsButton.Visible = 'on';
            
            
        end

        % Value changed function: ZeroButton
        function ZeroButtonValueChanged(app, event)
            
            app.winfits.zeros(app.currentplots.num)=app.ZeroButton.Value;
            
            %% update excluded state button %%
            switch app.ZeroButton.Value
                
                case 0
                    
                    app.NotZeroLabel.FontColor = [0.0 0.7 0.0];
                    
                    app.NotZeroLabel.Text = 'Window not zeroed';
                    
                    app.ZeroButton.Text = 'Zero';
                    
                case 1
                    
                    app.NotZeroLabel.FontColor = [0.6 0.0 0.0];
                    
                    app.NotZeroLabel.Text = 'Window is zeroed';
                    
                    app.ZeroButton.Text = 'Un-zero';
                    
            end
            
            
            %setting color for trace plot
            if app.winfits.exclusions(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.excluded;
            elseif app.winfits.zeros(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.zeroed;
            elseif app.currentplots.fod.amp(app.currentplots.num)>app.ref.stats(app.currentplots.num).rms
                app.currentplots.colors.temp=app.currentplots.colors.aboveRMS;
            else
                app.currentplots.colors.temp=app.currentplots.colors.belowRMS;
            end
            
            
            %current trace
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            
            hold(app.axCurrent, 'off')
            
            
        end

        % Button pushed function: SeefulltraceButton
        function SeefulltraceButtonPushed(app, event)
            
            %full fluo plot
            figure('Color','white')
            plot(app.currentplots.trace.xaxfull,app.ref.stats(app.currentplots.num).f)
            
        end

        % Value changed function: AdjustcrvfitwindowSpinner
        function AdjustcrvfitwindowSpinnerValueChanged(app, event)
            
            %pulling in vars
            value = round(app.AdjustcrvfitwindowSpinner.Value/app.AdjustcrvfitwindowSpinner.Step);
            
            xax=app.currentplots.trace.xax;
            num=app.currentplots.num;
            stats=app.ref.stats(app.currentplots.num);
            
            %updating with value
            stats.wincrv.fitwin=value;
            stats.wincrv.end=stats.wincrv.st+value;
            
            [stats.wincrv.obj,stats.wincrv.gof]=fit(...
                (xax(stats.wincrv.st:stats.wincrv.end).'),...
                (stats.ave(stats.wincrv.st:stats.wincrv.end).'),...
                'exp2');
            
            app.currentplots.fod.amp(num)=1-...
                (stats.wincrv.obj(xax(stats.wincrv.st)));
            
            stats.wincrv.plotx=xax(stats.wincrv.st:stats.wincrv.end);
            stats.wincrv.ploty=stats.wincrv.obj(stats.wincrv.plotx);
            
            app.ref.stats(num).wincrv=stats.wincrv;
            
            
            %Max amp vs distance
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.amp(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)
            
            
            %current trace
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).t_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).b_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            patch(app.axCurrent,[app.currentplots.trace.xax,fliplr(app.currentplots.trace.xax)],...
                [app.ref.stats(app.currentplots.num).t_stderr,fliplr(app.ref.stats(app.currentplots.num).b_stderr)],...
                [0.97 0.97 0.97])
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            hold(app.axCurrent, 'off')
            
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(app.currentplots.fod.amp(app.currentplots.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(app.ref.stats(app.currentplots.num).wincrv.gof.adjrsquare));
            
            %Because decimal points make things difficult:
            app.AdjustcrvfitwindowSpinner.Value=value*app.AdjustcrvfitwindowSpinner.Step;
            
            
        end

        % Value changed function: AdjustpeakwindowSpinner
        function AdjustpeakwindowSpinnerValueChanged(app, event)
            
            value = round(app.AdjustpeakwindowSpinner.Value/app.AdjustpeakwindowSpinner.Step);
            
            %pulling in vars
            xax=app.currentplots.trace.xax;
            num=app.currentplots.num;
            stats=app.ref.stats(num);
            
            
            %calc new max
            [~,tmax]=max(app.ref.parameters.stim(3)*stats.ave(...
                (app.ref.parameters.stim(1)/app.ref.parameters.im(1)):...
                ((app.ref.parameters.stim(1)/app.ref.parameters.im(1))+value)));
            tmax=tmax-1;
            
            %updating parameters
            stats.wincrv.peakwin=value;
            
            app.currentplots.fod.tmax(app.currentplots.num)=tmax;
            
            stats.wincrv.st=tmax+(app.ref.parameters.stim(1)/app.ref.parameters.im(1));%st time in frames
            stats.wincrv.end=stats.wincrv.st+stats.wincrv.fitwin;
            
            [stats.wincrv.obj,stats.wincrv.gof]=fit(...
                (xax(stats.wincrv.st:stats.wincrv.end).'),...
                (stats.ave(stats.wincrv.st:stats.wincrv.end).'),...
                'exp2');
            
            app.currentplots.fod.amp(num)=1-...
                (stats.wincrv.obj(xax(stats.wincrv.st)));
            
            stats.wincrv.plotx=xax(stats.wincrv.st:stats.wincrv.end);
            stats.wincrv.ploty=stats.wincrv.obj(stats.wincrv.plotx);
            
            app.ref.stats(num).wincrv=stats.wincrv;


            %Max amp vs distance plot
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.amp(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)


            %current trace
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).t_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).b_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            patch(app.axCurrent,[app.currentplots.trace.xax,fliplr(app.currentplots.trace.xax)],...
                [app.ref.stats(app.currentplots.num).t_stderr,fliplr(app.ref.stats(app.currentplots.num).b_stderr)],...
                [0.97 0.97 0.97])
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            hold(app.axCurrent, 'off')
            
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(app.currentplots.fod.amp(app.currentplots.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(app.ref.stats(app.currentplots.num).wincrv.gof.adjrsquare));
            
            %Adjfit spinner limit
            app.AdjustcrvfitwindowSpinner.Limits = [app.AdjustcrvfitwindowSpinner.Step (app.ref.parameters.stim(2)/app.ref.parameters.im(2))-...
                (app.currentplots.fod.tmax(app.currentplots.num)*app.AdjustcrvfitwindowSpinner.Step)];
            
            
            %Because decimal points make things difficult:
            app.AdjustpeakwindowSpinner.Value=value*app.AdjustpeakwindowSpinner.Step;
            
            
        end

        % Value changed function: ZerostoendButton
        function ZerostoendButtonValueChanged(app, event)
            
            app.winfits.zeros(app.currentplots.num:end) = app.ZerostoendButton.Value;
            
            
            %% update zero state button %%
            switch app.winfits.zeros(app.currentplots.num)
                
                case 0
                    
                    app.NotZeroLabel.FontColor = [0.0 0.7 0.0];
                    
                    app.NotZeroLabel.Text = 'Window not zeroed';
                    
                    app.ZeroButton.Text = 'Zero';
                    
                case 1
                    
                    app.NotZeroLabel.FontColor = [0.6 0.0 0.0];
                    
                    app.NotZeroLabel.Text = 'Window is zeroed';
                    
                    app.ZeroButton.Text = 'Un-zero';
                    
            end
            
            %setting color for trace plot
            if app.winfits.exclusions(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.excluded;
            elseif app.winfits.zeros(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.zeroed;
            elseif app.currentplots.fod.amp(app.currentplots.num)>app.ref.stats(app.currentplots.num).rms
                app.currentplots.colors.temp=app.currentplots.colors.aboveRMS;
            else
                app.currentplots.colors.temp=app.currentplots.colors.belowRMS;
            end
            
            %current trace
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            
            hold(app.axCurrent, 'off')
            
            app.ZerostoendButton.Value=0;
            
            
        end

        % Value changed function: AdjtoendButton
        function AdjtoendButtonValueChanged(app, event)
            value = app.AdjtoendButton.Value;
            
            %% update populate state button while calculating%%
            switch value
                case 0
                    app.AdjtoendButton.Text = '>> Adj to end';
                case 1
                    app.AdjtoendButton.Text = 'Populating...';
            end


            peakval = round(app.AdjustpeakwindowSpinner.Value/app.AdjustpeakwindowSpinner.Step);
            fitval = round(app.AdjustcrvfitwindowSpinner.Value/app.AdjustcrvfitwindowSpinner.Step);
            
            for i=app.currentplots.num:size(app.ref.stats,1)
                [~,app.currentplots.fod.tmax(i)]=max(app.ref.parameters.stim(3)*app.ref.stats(i).ave(...
                    (app.ref.parameters.stim(1)/app.ref.parameters.im(1)):...
                    ((app.ref.parameters.stim(1)/app.ref.parameters.im(1))+peakval)));
                app.currentplots.fod.tmax(i)=app.currentplots.fod.tmax(i)-1;
                
                app.ref.stats(i).wincrv.peakwin=peakval;
                app.ref.stats(i).wincrv.fitwin=fitval;
                
                app.ref.stats(i).wincrv.st=app.currentplots.fod.tmax(i)+...
                    (app.ref.parameters.stim(1)/app.ref.parameters.im(1));%st time in frames
                
                app.ref.stats(i).wincrv.end=app.ref.stats(i).wincrv.st+fitval;
                
                
                [app.ref.stats(i).wincrv.obj,app.ref.stats(i).wincrv.gof]=fit(...
                    (app.currentplots.trace.xax(...
                    app.ref.stats(i).wincrv.st:app.ref.stats(i).wincrv.end).'),...
                    (app.ref.stats(i).ave(...
                    app.ref.stats(i).wincrv.st:app.ref.stats(i).wincrv.end).'),...
                    'exp2');
                
                app.currentplots.fod.amp(i)=1-...
                    (app.ref.stats(i).wincrv.obj(...
                    app.currentplots.trace.xax(app.ref.stats(i).wincrv.st)));
                
                app.ref.stats(i).wincrv.plotx=app.currentplots.trace.xax(...
                    app.ref.stats(i).wincrv.st:app.ref.stats(i).wincrv.end);
                
                app.ref.stats(i).wincrv.ploty=...
                    app.ref.stats(i).wincrv.obj(app.ref.stats(i).wincrv.plotx);
            end
            
            
            %% plotting new vals %%
            %setting color for trace plot
            if app.winfits.exclusions(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.excluded;
            elseif app.winfits.zeros(app.currentplots.num)==1
                app.currentplots.colors.temp=app.currentplots.colors.zeroed;
            elseif app.currentplots.fod.amp(app.currentplots.num)>app.ref.stats(app.currentplots.num).rms
                app.currentplots.colors.temp=app.currentplots.colors.aboveRMS;
            else
                app.currentplots.colors.temp=app.currentplots.colors.belowRMS;
            end


            %% plots %%


            %Max amp vs distance
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.amp(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)
            
            
            %Max time vs distance
            yyaxis(app.axTvD,'left')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axTvD,'time to ASAP3er peak (frames)', 'FontSize',12)
            hold(app.axTvD, 'on')
            plot(app.axTvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.tmax(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axTvD, 'off')
            yyaxis(app.axTvD,'right')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axTvD,'time to 594 peak (frames)', 'FontSize',12)
            
            
            %current trace
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).t_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).b_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            patch(app.axCurrent,[app.currentplots.trace.xax,fliplr(app.currentplots.trace.xax)],...
                [app.ref.stats(app.currentplots.num).t_stderr,fliplr(app.ref.stats(app.currentplots.num).b_stderr)],...
                [0.97 0.97 0.97])
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            
            hold(app.axCurrent, 'off')
            
            
            %% updating boxes %%
            
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(app.currentplots.fod.amp(app.currentplots.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(app.ref.stats(app.currentplots.num).wincrv.gof.adjrsquare));
            
            %Adjfit spinner limit
            app.AdjustcrvfitwindowSpinner.Limits = [app.AdjustcrvfitwindowSpinner.Step (app.ref.parameters.stim(2)/app.ref.parameters.im(2))-...
                (app.currentplots.fod.tmax(app.currentplots.num)*app.AdjustcrvfitwindowSpinner.Step)];
            
            
            %Because decimal points make things difficult:
            app.AdjustpeakwindowSpinner.Value=peakval*app.AdjustpeakwindowSpinner.Step;
            app.AdjustcrvfitwindowSpinner.Value=fitval*app.AdjustcrvfitwindowSpinner.Step;
            
            app.AdjtoendButton.Value=0;
            
            switch app.AdjtoendButton.Value
                case 0
                    app.AdjtoendButton.Text = '>> Adj to end';
                case 1
                    app.AdjtoendButton.Text = 'Populating...';
            end
            
            
        end

        % Menu selected function: AppendpreviousMenu
        function AppendpreviousMenuSelected(app, event)
            %% Getting path %%


            %importing ref property
            pathref=app.paths;
            
            %default option is just to start at pwd, otherwise use saved path
            choose=0;
            if isfield(pathref,'infil')==1
                if isfield(pathref.infil,'dirpath')==1
                    if pathref.infil.dirpath==0
                        
                    else
                        choose=1;
                    end
                end
            end
            
            %choosing old path or new one
            if choose==1
                
                [pathref.append.filnam,pathref.append.dirpath, ~]=...
                    uigetfile(fullfile(pathref.infil.dirpath,'*.mat'));
                
                %updating app.paths
                app.paths.append.filnam=pathref.append.filnam;
                app.paths.append.dirpath=pathref.append.dirpath;
                
            else
                [pathref.append.filnam,pathref.append.dirpath, ~]=uigetfile('.mat');
                
                %updating app.paths
                app.paths.append.filnam=pathref.append.filnam;
                app.paths.append.dirpath=pathref.append.dirpath;
            end
            
            disp(' ');
            disp(' ');
            disp(strcat('appende image is:    "',pathref.append.filnam,'"'));
            
            %getting variables from workspace
            appended=load(fullfile(pathref.append.dirpath,pathref.append.filnam));
            
            app.currentplots=appended.DistEst.curr;
            app.winfits=appended.DistEst.fits;
            app.ref.stats=appended.DistEst.stats;


            %Max amp vs distance plot
            yyaxis(app.axAvD,'left')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axAvD,'ASAP3er dFoF', 'FontSize',12)
            hold(app.axAvD, 'on')
            plot(app.axAvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.amp(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axAvD, 'off')
            yyaxis(app.axAvD,'right')
            plot(app.axAvD,app.currentplots.fod.xax,...
                app.currentplots.fod.amp594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axAvD,'594au', 'FontSize',12)
            
            
            %Max time vs distance
            yyaxis(app.axTvD,'left')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax,...
                'Color',[0 0.6 0],'LineWidth',1,'Marker','o')
            ylabel(app.axTvD,'time to ASAP3er peak (frames)', 'FontSize',12)
            hold(app.axTvD, 'on')
            plot(app.axTvD,app.currentplots.fod.xax(app.currentplots.num),...
                app.currentplots.fod.tmax(app.currentplots.num),...
                'Color',[0.95 0 0],'LineWidth',1,'Marker','o')
            hold(app.axTvD, 'off')
            yyaxis(app.axTvD,'right')
            plot(app.axTvD,app.currentplots.fod.xax,...
                app.currentplots.fod.tmax594,...
                'Color',[0.7 0.6 0.6],'LineWidth',1,'Marker','o','MarkerSize',4)
            ylabel(app.axTvD,'time to 594 peak (frames)', 'FontSize',12)
            
            
            %current trace
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).t_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            hold(app.axCurrent, 'on')
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).b_stderr,...
                'Color',app.currentplots.colors.temp,'LineWidth',1)
            
            patch(app.axCurrent,[app.currentplots.trace.xax,fliplr(app.currentplots.trace.xax)],...
                [app.ref.stats(app.currentplots.num).t_stderr,fliplr(app.ref.stats(app.currentplots.num).b_stderr)],...
                [0.97 0.97 0.97])
            
            plot(app.axCurrent,app.currentplots.trace.xax,...
                app.ref.stats(app.currentplots.num).ave,...
                'Color',app.currentplots.colors.temp,'LineWidth',3)
            
            plot(app.axCurrent,app.ref.stats(app.currentplots.num).wincrv.plotx,...
                app.ref.stats(app.currentplots.num).wincrv.ploty,...
                'Color',[0.69 0 0],'LineWidth',1)
            hold(app.axCurrent, 'off')
            
            
            %Max amp box
            app.MaxampLabel.Text = strcat('Max amp = ',num2str(app.currentplots.fod.amp(app.currentplots.num)));
            
            %Rsquared box
            app.RSquaredLabel.Text = strcat('RSquared = ',num2str(app.ref.stats(app.currentplots.num).wincrv.gof.adjrsquare));
            
            
            app.FitdataButton_2.Visible = 'on';
            
            
            app.StartnotselectedLabel.Text = strcat('Start is window',num2str(app.winfits.st));
            
            
            app.EndnotselectedLabel.Text = strcat('End is window',num2str(app.winfits.end));


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportDataMenu = uimenu(app.Menu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDataMenuSelected, true);
            app.ImportDataMenu.Text = 'Import Data';
            app.AppendpreviousMenu = uimenu(app.Menu);
            app.AppendpreviousMenu.MenuSelectedFcn = createCallbackFcn(app, @AppendpreviousMenuSelected, true);
            app.AppendpreviousMenu.Text = 'Append previous';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Menu_3 = uimenu(app.Menu2);
            app.Menu_3.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReviewaplitudepointsTab = uitab(app.TabGroup);
            app.ReviewaplitudepointsTab.Title = 'Review aplitude points';
            app.axAvD = uiaxes(app.ReviewaplitudepointsTab);
            title(app.axAvD, 'Max amp vs dist')
            xlabel(app.axAvD, 'window center (um)')
            app.axAvD.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axTvD = uiaxes(app.ReviewaplitudepointsTab);
            title(app.axTvD, 'Tmax vs distance')
            xlabel(app.axTvD, 'window center (um)')
            app.axTvD.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axCurrent = uiaxes(app.ReviewaplitudepointsTab);
            title(app.axCurrent, 'Window response')
            xlabel(app.axCurrent, 'seconds')
            ylabel(app.axCurrent, 'dFoF')
            app.axCurrent.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Panel = uipanel(app.ReviewaplitudepointsTab);
            app.SelectstartptButton = uibutton(app.Panel, 'push');
            app.SelectstartptButton.ButtonPushedFcn = createCallbackFcn(app, @SelectstartptButtonPushed, true);
            app.SelectstartptButton.Visible = 'off';
            app.SelectstartptButton.Text = 'Select start pt.';
            app.StartnotselectedLabel = uilabel(app.Panel);
            app.StartnotselectedLabel.Text = 'Start not selected';
            app.SelectendptButton = uibutton(app.Panel, 'push');
            app.SelectendptButton.ButtonPushedFcn = createCallbackFcn(app, @SelectendptButtonPushed, true);
            app.SelectendptButton.Visible = 'off';
            app.SelectendptButton.Text = 'Select end pt.';
            app.EndnotselectedLabel = uilabel(app.Panel);
            app.EndnotselectedLabel.Text = 'End not selected';
            app.Panel_2 = uipanel(app.ReviewaplitudepointsTab);
            app.SeerefimandROIsButton = uibutton(app.Panel_2, 'push');
            app.SeerefimandROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SeerefimandROIsButtonPushed, true);
            app.SeerefimandROIsButton.Visible = 'off';
            app.SeerefimandROIsButton.Text = 'See ref im and ROIs >>';
            app.SeefulltraceButton = uibutton(app.Panel_2, 'push');
            app.SeefulltraceButton.ButtonPushedFcn = createCallbackFcn(app, @SeefulltraceButtonPushed, true);
            app.SeefulltraceButton.Visible = 'off';
            app.SeefulltraceButton.Text = 'See full trace';
            app.WindowLabel = uilabel(app.ReviewaplitudepointsTab);
            app.WindowLabel.Text = 'Window #';
            app.WindownumberSpinner = uispinner(app.ReviewaplitudepointsTab);
            app.WindownumberSpinner.Limits = [1 60];
            app.WindownumberSpinner.RoundFractionalValues = 'on';
            app.WindownumberSpinner.ValueDisplayFormat = '%.0f';
            app.WindownumberSpinner.ValueChangedFcn = createCallbackFcn(app, @WindownumberSpinnerValueChanged, true);
            app.WindownumberSpinner.Visible = 'off';
            app.WindownumberSpinner.Value = 1;
            app.ExcludeButton = uibutton(app.ReviewaplitudepointsTab, 'state');
            app.ExcludeButton.ValueChangedFcn = createCallbackFcn(app, @ExcludeButtonValueChanged, true);
            app.ExcludeButton.Visible = 'off';
            app.ExcludeButton.Text = 'Exclude';
            app.NoexclusionsyetLabel = uilabel(app.ReviewaplitudepointsTab);
            app.NoexclusionsyetLabel.Visible = 'off';
            app.NoexclusionsyetLabel.Text = 'No exclusions yet';
            app.BaselineRMSLabel = uilabel(app.ReviewaplitudepointsTab);
            app.BaselineRMSLabel.Visible = 'off';
            app.BaselineRMSLabel.Text = 'Baseline RMS = ';
            app.MaxampLabel = uilabel(app.ReviewaplitudepointsTab);
            app.MaxampLabel.Visible = 'off';
            app.MaxampLabel.Text = 'Max amp = ';
            app.ZeroButton = uibutton(app.ReviewaplitudepointsTab, 'state');
            app.ZeroButton.ValueChangedFcn = createCallbackFcn(app, @ZeroButtonValueChanged, true);
            app.ZeroButton.Visible = 'off';
            app.ZeroButton.Text = 'Make zero';
            app.NotZeroLabel = uilabel(app.ReviewaplitudepointsTab);
            app.NotZeroLabel.Visible = 'off';
            app.NotZeroLabel.Text = 'Not zeroed';
            app.AdjustpeakwindowSpinnerLabel = uilabel(app.ReviewaplitudepointsTab);
            app.AdjustpeakwindowSpinnerLabel.Text = 'Adjust peak window';
            app.AdjustpeakwindowSpinner = uispinner(app.ReviewaplitudepointsTab);
            app.AdjustpeakwindowSpinner.Step = 0.1;
            app.AdjustpeakwindowSpinner.Limits = [0 10];
            app.AdjustpeakwindowSpinner.ValueChangedFcn = createCallbackFcn(app, @AdjustpeakwindowSpinnerValueChanged, true);
            app.AdjustpeakwindowSpinner.Visible = 'off';
            app.AdjustpeakwindowSpinner.Value = 1;
            app.AdjustcrvfitwindowSpinnerLabel = uilabel(app.ReviewaplitudepointsTab);
            app.AdjustcrvfitwindowSpinnerLabel.Text = 'Adjust crv fit window';
            app.AdjustcrvfitwindowSpinner = uispinner(app.ReviewaplitudepointsTab);
            app.AdjustcrvfitwindowSpinner.Limits = [0 10];
            app.AdjustcrvfitwindowSpinner.ValueChangedFcn = createCallbackFcn(app, @AdjustcrvfitwindowSpinnerValueChanged, true);
            app.AdjustcrvfitwindowSpinner.Visible = 'off';
            app.AdjustcrvfitwindowSpinner.Value = 1;
            app.ZerostoendButton = uibutton(app.ReviewaplitudepointsTab, 'state');
            app.ZerostoendButton.ValueChangedFcn = createCallbackFcn(app, @ZerostoendButtonValueChanged, true);
            app.ZerostoendButton.Visible = 'off';
            app.ZerostoendButton.Text = 'Zeros to end';
            app.RSquaredLabel = uilabel(app.ReviewaplitudepointsTab);
            app.RSquaredLabel.Visible = 'off';
            app.RSquaredLabel.Text = 'RSquared = ';
            app.AdjtoendButton = uibutton(app.ReviewaplitudepointsTab, 'state');
            app.AdjtoendButton.ValueChangedFcn = createCallbackFcn(app, @AdjtoendButtonValueChanged, true);
            app.AdjtoendButton.Visible = 'off';
            app.AdjtoendButton.Text = '>> Adj to end';
            app.EstimateDistTab = uitab(app.TabGroup);
            app.EstimateDistTab.Title = 'Estimate Dist';
            app.axCrveFit = uiaxes(app.EstimateDistTab);
            title(app.axCrveFit, 'Poly curve fit to max amp vs dist')
            xlabel(app.axCrveFit, 'window center (um)')
            app.SaveresultsButton = uibutton(app.EstimateDistTab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @FitamplitudesButtonValueChanged, true);
            app.SaveresultsButton.Visible = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.PolycurveLabel = uilabel(app.EstimateDistTab);
            app.PolycurveLabel.Visible = 'off';
            app.PolycurveLabel.Text = 'Polycurve';
            app.BlackpointsfitredcurvefitLabel = uilabel(app.EstimateDistTab);
            app.BlackpointsfitredcurvefitLabel.Text = 'Black = points fit, red = curve fit   ';
            app.EstimateddistanceLabel = uilabel(app.EstimateDistTab);
            app.EstimateddistanceLabel.Visible = 'off';
            app.EstimateddistanceLabel.Text = 'Estimated distance';
            app.FitdataButton_2 = uibutton(app.EstimateDistTab, 'push');
            app.FitdataButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitdataButton_2Pushed, true);
            app.FitdataButton_2.Visible = 'off';
            app.FitdataButton_2.Text = 'Fit data >>';
            app.ExptsavedLabel = uilabel(app.EstimateDistTab);
            app.ExptsavedLabel.Visible = 'off';
            app.ExptsavedLabel.Text = 'Expt. saved';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ampdist_fit_exp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end